classdef Bamocar_and_bench_control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RetoursmoteurPanel           matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        ReceivedLabel                matlab.ui.control.Label
        ValeurLabel                  matlab.ui.control.Label
        IDRegistreEditField_2        matlab.ui.control.EditField
        IDRegistreEditField_2Label   matlab.ui.control.Label
        VitesseBamocarRPMGauge       matlab.ui.control.SemicircularGauge
        VitesseBamocarRPMGaugeLabel  matlab.ui.control.Label
        TensionbusVGauge             matlab.ui.control.SemicircularGauge
        TensionbusVGaugeLabel        matlab.ui.control.Label
        MettrejourvaleursButton      matlab.ui.control.Button
        ContrlebancPanel             matlab.ui.container.Panel
        StatutbancLabel              matlab.ui.control.Label
        VitesseEditField             matlab.ui.control.NumericEditField
        VitesseEditFieldLabel        matlab.ui.control.Label
        CoupleEditField              matlab.ui.control.NumericEditField
        CoupleEditFieldLabel         matlab.ui.control.Label
        NonconnectLabel              matlab.ui.control.Label
        STOPButton                   matlab.ui.control.Button
        STARTButton                  matlab.ui.control.Button
        ContrlemoteurPanel           matlab.ui.container.Panel
        ControlersPanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        EnvoicommandeButton          matlab.ui.control.Button
        DLCEditField                 matlab.ui.control.NumericEditField
        DLCEditFieldLabel            matlab.ui.control.Label
        ValeurEditField              matlab.ui.control.EditField
        ValeurEditFieldLabel         matlab.ui.control.Label
        IDRegistreEditField          matlab.ui.control.EditField
        IDRegistreEditFieldLabel     matlab.ui.control.Label
        CoupleunitinconnueKnob       matlab.ui.control.Knob
        CoupleunitinconnueKnobLabel  matlab.ui.control.Label
        VitesseRPMKnob               matlab.ui.control.Knob
        VitesseRPMKnobLabel          matlab.ui.control.Label
        CANConnectLabel              matlab.ui.control.Label
        StatutCANLabel               matlab.ui.control.Label
        STOPCONNEXIONCANButton       matlab.ui.control.Button
        STARTCONNEXIONCANButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Couple % Description
        Vitesse
        CanChannel
        Speed_max=3600
        Voltage_max=500
        Server
    end
    
    
    methods (Access = private)

        function close_com(app)
            app.send_data("0x51","0x0004",3)
            stop(app.CanChannel)
        end
        
        function send_data(app,data_id,value,dlc,bamocar_receive_id)
            canch1=app.CanChannel;
        
            Defaults={"0x00",0,3,"0x201"};
            if nargin>=2
                Defaults{1}=data_id;
            end
            if nargin>=3
                Defaults{2}=int32(str2double(value));
                
            end
            if nargin>=4
                Defaults{3}=dlc;
            end
            if nargin>=5
                Defaults{4}=bamocar_receive_id;
            end
            data=hex2dec(Defaults{1});
            val=Defaults{2};
            bmc_rcv_id=hex2dec(Defaults{4});
            valbin=dec2bin(val,8);
            dlc_f=Defaults{3};
            dlc_r=ceil(strlength(valbin)/8);
            messageout = canMessage(bmc_rcv_id,false,dlc_f);
            
            strout=dec2bin(data,8);
            while strlength(valbin)<dlc_r*8
                valbin=strcat("0",valbin);
            end
            valbin=string(valbin);
            valbin=valbin{1};
            for k=1:dlc_r
                strout=strcat(valbin((dlc_r-k)*8+1:(dlc_r-k+1)*8),strout);
            end
            for k=dlc_r:dlc_f-2
                strout=strcat("00000000",strout);
            end
            out_dec=bin2dec(strout);
        
            pack(messageout,uint64(out_dec),0,dlc_f*8,'LittleEndian');
            transmit(canch1,messageout);
        end

        function received_value = receive_data(app,data_id,bamocar_receive_id,bamocar_send_id)
            canch1 = app.CanChannel;
        
            Defaults={"0x00","0x201","0x181"};
            if nargin>=2
                Defaults{1}=data_id;
            end
            if nargin>=3
                Defaults{2}=bamocar_receive_id;
            end
            if nargin>=4
                Defaults{3}=bamocar_send_id;
            end
            data=hex2dec(Defaults{1});
            bmc_rcv_id=hex2dec(Defaults{2});
            bmc_sd_id=hex2dec(Defaults{3});
            ask_data_id=hex2dec("0x3D");
        
            messageout = canMessage(bmc_rcv_id,false,3);
            
            out_dec=bin2dec(strcat(dec2bin(hex2dec("0x00"),8),dec2bin(data,8),dec2bin(ask_data_id,8)));
            pack(messageout,uint32(out_dec),0,24,'LittleEndian')
        
            transmit(canch1,messageout);
        
            received_value=0;
            count=0;
            while received_value==0
                count=count+1;
                messagein = receive(canch1,1);
                if ~isempty(messagein)
                    if messagein.ID==bmc_sd_id && messagein.Data(1)==data
                        len=messagein.Length;
                        strout="";
                        for k=2:len-1
                            strout=strcat(dec2bin(messagein.Data(k),8),strout);
                        end
                        received_value=bin2dec(strout);
                        return
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.NonconnectLabel.Text = 'En attente connexion';
            try
                app.Server=tcpserver("192.168.0.10", 2000);
                pause(0.1)
                app.NonconnectLabel.Text = 'Connecté';
                                
            catch exception
                %fclose(t);
                rethrow(exception);
            end           
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            clear("app.Server")
            app.NonconnectLabel.Text = 'Déconnecté';
        end

        % Button pushed function: STARTCONNEXIONCANButton
        function STARTCONNEXIONCANButtonPushed(app, event)
            try
                app.CANConnectLabel.Text="En cours de connexion";
                pause(0.1)
                if ~isa(app.CanChannel,"can.Channel")
                    app.CanChannel = canChannel('PEAK-System','PCAN_USBBUS1');
                end
                configBusSpeed(app.CanChannel,500000);
                start(app.CanChannel)
                app.ControlersPanel.Enable="on";
                app.RetoursmoteurPanel.Enable="on";
                app.CANConnectLabel.Text="Connexion établie";
                app.Speed_max=app.receive_data("0xc8");
                app.VitesseRPMKnob.Limits=[0,app.Speed_max];
                app.VitesseBamocarRPMGauge.Limits=[0,app.Speed_max+100];
                app.Voltage_max=app.receive_data("0x64");
                app.TensionbusVGauge.Limits=[0,app.Voltage_max+100];
                app.send_data("0x51","0x0000",3)

            catch exception
                app.CANConnectLabel.Text="Echec de la connexion";
                rethrow(exception)

            end
        end

        % Button pushed function: STOPCONNEXIONCANButton
        function STOPCONNEXIONCANButtonPushed(app, event)
            app.close_com()
            app.RetoursmoteurPanel.Enable="off";
            app.ControlersPanel.Enable="off";
            app.CANConnectLabel.Text="Non connecté";
        end

        % Value changed function: VitesseRPMKnob
        function VitesseRPMKnobValueChanged(app, event)
            value = app.VitesseRPMKnob.Value;
            app.CoupleunitinconnueKnob.Value=0;
            to_transmit=floor((value/app.Speed_max)*2^15);
            app.send_data("0x31",to_transmit,3)
            
        end

        % Value changed function: CoupleunitinconnueKnob
        function CoupleunitinconnueKnobValueChanged(app, event)
            value = app.CoupleunitinconnueKnob.Value;
            app.VitesseRPMKnob.Value=0;
            to_transmit=floor(value*2^15);
            app.send_data("0x90",to_transmit,3)

        end

        % Button pushed function: MettrejourvaleursButton
        function MettrejourvaleursButtonPushed(app, event)
            app.TensionbusVGauge.Value=app.receive_data("0x66")/(2^16)*app.Voltage_max;
            app.VitesseBamocarRPMGauge.Value=app.receive_data("0x30")/(2^15)*app.Speed_max;
            app.ReceivedLabel.Text = string(app.receive_data(app.IDRegistreEditField_2.Value));
            
            %Maj vitesse automate
            A=read(app.Server,2);
            B=flipud(A);
            C=uint8(B);
            D=typecast(C,'int16');
            D=double(D);
            app.VitesseEditField.Value = D; %fread(t,1);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try app.close_com()
            catch
            end
            delete(app)
            
        end

        % Button pushed function: EnvoicommandeButton
        function EnvoicommandeButtonPushed(app, event)
            id = string(app.IDRegistreEditField.Value);
            val = string(app.ValeurEditField.Value);
            dlc=app.DLCEditField.Value;
            app.send_data(id,val,dlc)
        end

        % Value changed function: CoupleEditField
        function CoupleEditFieldValueChanged(app, event)
            app.Couple = app.CoupleEditField.Value;
            value=dec2bin(floor(app.Couple/0.075),16);
            value=swapbytes(value);
            % bit1=value(1:8);
            % bit2=value(9:16);
            % strbin=strcat(bit2,bit1);
            % to_transmit=bin2dec(strbin);

            %app.Couple = app.Vitesse * 5 :10;
            %E=typecast(int16(app.Couple),'int8');
            %F=flipud(E);
            fait=0;
            count=0;
            while ~fait && count<10
                try
                    write(app.Server,value,"uint16");
                    fait=1;
                catch
                    count=count+1;
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ContrlemoteurPanel = uipanel(app.UIFigure);
            app.ContrlemoteurPanel.Title = 'Contrôle moteur';
            app.STARTCONNEXIONCANButton = uibutton(app.ContrlemoteurPanel, 'push');
            app.STARTCONNEXIONCANButton.ButtonPushedFcn = createCallbackFcn(app, @STARTCONNEXIONCANButtonPushed, true);
            app.STARTCONNEXIONCANButton.Text = 'START CONNEXION CAN';
            app.STOPCONNEXIONCANButton = uibutton(app.ContrlemoteurPanel, 'push');
            app.STOPCONNEXIONCANButton.ButtonPushedFcn = createCallbackFcn(app, @STOPCONNEXIONCANButtonPushed, true);
            app.STOPCONNEXIONCANButton.Text = 'STOP CONNEXION CAN';
            app.StatutCANLabel = uilabel(app.ContrlemoteurPanel);
            app.StatutCANLabel.Text = 'Statut CAN :';
            app.CANConnectLabel = uilabel(app.ContrlemoteurPanel);
            app.CANConnectLabel.Text = 'Non connecté';
            app.ControlersPanel = uipanel(app.ContrlemoteurPanel);
            app.ControlersPanel.Enable = 'off';
            app.GridLayout2 = uigridlayout(app.ControlersPanel);
            app.VitesseRPMKnobLabel = uilabel(app.GridLayout2);
            app.VitesseRPMKnobLabel.Text = 'Vitesse (RPM)';
            app.VitesseRPMKnob = uiknob(app.GridLayout2, 'continuous');
            app.VitesseRPMKnob.Limits = [0 3600];
            app.VitesseRPMKnob.ValueChangedFcn = createCallbackFcn(app, @VitesseRPMKnobValueChanged, true);
            app.CoupleunitinconnueKnobLabel = uilabel(app.GridLayout2);
            app.CoupleunitinconnueKnobLabel.Text = 'Couple (%,unité inconnue)';
            app.CoupleunitinconnueKnob = uiknob(app.GridLayout2, 'continuous');
            app.CoupleunitinconnueKnob.ValueChangedFcn = createCallbackFcn(app, @CoupleunitinconnueKnobValueChanged, true);
            app.IDRegistreEditFieldLabel = uilabel(app.GridLayout2);
            app.IDRegistreEditFieldLabel.Text = 'ID Registre';
            app.IDRegistreEditField = uieditfield(app.GridLayout2, 'text');
            app.IDRegistreEditField.Value = '0x31';
            app.ValeurEditFieldLabel = uilabel(app.GridLayout2);
            app.ValeurEditFieldLabel.Text = 'Valeur ';
            app.ValeurEditField = uieditfield(app.GridLayout2, 'text');
            app.ValeurEditField.Value = '32000';
            app.DLCEditFieldLabel = uilabel(app.GridLayout2);
            app.DLCEditFieldLabel.Text = 'DLC';
            app.DLCEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DLCEditField.Limits = [3 5];
            app.DLCEditField.Value = 3;
            app.EnvoicommandeButton = uibutton(app.GridLayout2, 'push');
            app.EnvoicommandeButton.ButtonPushedFcn = createCallbackFcn(app, @EnvoicommandeButtonPushed, true);
            app.EnvoicommandeButton.Text = 'Envoi commande';
            app.ContrlebancPanel = uipanel(app.UIFigure);
            app.ContrlebancPanel.Title = 'Contrôle banc';
            app.STARTButton = uibutton(app.ContrlebancPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ContrlebancPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.NonconnectLabel = uilabel(app.ContrlebancPanel);
            app.NonconnectLabel.Text = 'Non connecté';
            app.CoupleEditFieldLabel = uilabel(app.ContrlebancPanel);
            app.CoupleEditFieldLabel.Text = 'Couple';
            app.CoupleEditField = uieditfield(app.ContrlebancPanel, 'numeric');
            app.CoupleEditField.AllowEmpty = 'on';
            app.CoupleEditField.ValueChangedFcn = createCallbackFcn(app, @CoupleEditFieldValueChanged, true);
            app.VitesseEditFieldLabel = uilabel(app.ContrlebancPanel);
            app.VitesseEditFieldLabel.Text = 'Vitesse';
            app.VitesseEditField = uieditfield(app.ContrlebancPanel, 'numeric');
            app.VitesseEditField.AllowEmpty = 'on';
            app.StatutbancLabel = uilabel(app.ContrlebancPanel);
            app.StatutbancLabel.Text = 'Statut banc :';
            app.RetoursmoteurPanel = uipanel(app.UIFigure);
            app.RetoursmoteurPanel.Enable = 'off';
            app.RetoursmoteurPanel.Title = 'Retours moteur';
            app.GridLayout = uigridlayout(app.RetoursmoteurPanel);
            app.MettrejourvaleursButton = uibutton(app.GridLayout, 'push');
            app.MettrejourvaleursButton.ButtonPushedFcn = createCallbackFcn(app, @MettrejourvaleursButtonPushed, true);
            app.MettrejourvaleursButton.Text = 'Mettre à jour valeurs';
            app.TensionbusVGaugeLabel = uilabel(app.GridLayout);
            app.TensionbusVGaugeLabel.Text = 'Tension bus (V)';
            app.TensionbusVGauge = uigauge(app.GridLayout, 'semicircular');
            app.TensionbusVGauge.Limits = [0 600];
            app.VitesseBamocarRPMGaugeLabel = uilabel(app.GridLayout);
            app.VitesseBamocarRPMGaugeLabel.Text = 'Vitesse Bamocar (RPM)';
            app.VitesseBamocarRPMGauge = uigauge(app.GridLayout, 'semicircular');
            app.VitesseBamocarRPMGauge.Limits = [0 3600];
            app.IDRegistreEditField_2Label = uilabel(app.GridLayout);
            app.IDRegistreEditField_2Label.Text = 'ID Registre';
            app.IDRegistreEditField_2 = uieditfield(app.GridLayout, 'text');
            app.IDRegistreEditField_2.Value = '0x51';
            app.ValeurLabel = uilabel(app.GridLayout);
            app.ValeurLabel.Text = 'Valeur';
            app.ReceivedLabel = uilabel(app.GridLayout);
            app.ReceivedLabel.Text = 'Received';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bamocar_and_bench_control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end