classdef Matlab_Siemens_BENCH01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        nonconnectLabel        matlab.ui.control.Label
        VitesseEditField       matlab.ui.control.NumericEditField
        VitesseEditFieldLabel  matlab.ui.control.Label
        CoupleEditField        matlab.ui.control.NumericEditField
        CoupleEditFieldLabel   matlab.ui.control.Label
        STOPButton             matlab.ui.control.Button
        STARTButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        Couple % Description
        Vitesse
        Run
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.Run=1;
            app.Vitesse = app.CoupleEditField.Value +1;
            t=tcpip("192.168.0.1", 2000, 'NetworkRole', 'server');
            try
                app.nonconnectLabel.Text = 'en attente connection';
                pause(0.1)
                fopen(t);
                app.nonconnectLabel.Text = 'connecté';
                while(app.Run)
                    pause(0.1)
                    A=fread(t,2);
                    B=flipud(A);
                    C=uint8(B);
                    D=typecast(C,'int16');
                    D=double(D);
                    app.VitesseEditField.Value = D; %fread(t,1);
                    app.Couple = app.CoupleEditField.Value;
                    %app.Couple = app.Vitesse * 5 :10;
                    %E=typecast(int16(app.Couple),'int8');
                    %F=flipud(E);
                    fwrite(t,app.Couple,'int16');
                end
                
            catch exception
                %fclose(t);
                rethrow(exception);
            end
            fclose(t);
            app.nonconnectLabel.Text = 'déconnecté';
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            
            app.Run = 0;
            app.nonconnectLabel.Text = 'déconnecté';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.CoupleEditFieldLabel = uilabel(app.UIFigure);
            app.CoupleEditFieldLabel.Text = 'Couple';
            app.CoupleEditField = uieditfield(app.UIFigure, 'numeric');
            app.VitesseEditFieldLabel = uilabel(app.UIFigure);
            app.VitesseEditFieldLabel.Text = 'Vitesse';
            app.VitesseEditField = uieditfield(app.UIFigure, 'numeric');
            app.nonconnectLabel = uilabel(app.UIFigure);
            app.nonconnectLabel.Text = 'non connecté';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Matlab_Siemens_BENCH01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end